classdef final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GrficadesealesPanel       matlab.ui.container.Panel
        grafico                   matlab.ui.control.UIAxes
        ESTADOLabel               matlab.ui.control.Label
        estado                    matlab.ui.control.EditField
        Panel_2                   matlab.ui.container.Panel
        SealDropDownLabel         matlab.ui.control.Label
        signal                    matlab.ui.control.DropDown
        FrecuenciaEditFieldLabel  matlab.ui.control.Label
        frec                      matlab.ui.control.NumericEditField
        ArmnicosEditFieldLabel    matlab.ui.control.Label
        armonicos                 matlab.ui.control.NumericEditField
        ECMEditField_2Label       matlab.ui.control.Label
        errormedio                matlab.ui.control.NumericEditField
        CriteriodecortePanel      matlab.ui.container.Panel
        Switch                    matlab.ui.control.Switch
        Panel_3                   matlab.ui.container.Panel
        calcular                  matlab.ui.control.Button
        ResultadosPanel           matlab.ui.container.Panel
        GibbsEditFieldLabel       matlab.ui.control.Label
        gibbs                     matlab.ui.control.NumericEditField
        ECMEditFieldLabel         matlab.ui.control.Label
        ecmresult                 matlab.ui.control.NumericEditField
        inicio                    matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: estado
        function estadoValueChanged(app, event)
          
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == '1'
                app.armonicos.Enable = 'on';
                app.errormedio.Enable = 'off';
                app.grafico.cla;
                
            else
                app.armonicos.Enable = 'off';
                app.errormedio.Enable = 'on';
                app.grafico.cla;
                
            end
            
        end

        % Button pushed function: inicio
        function inicioButtonPushed(app, event)
        addpath('Funciones')
        
        app.frec.Enable = 'on';
        app.signal.Enable = 'on';
        %app.amp.Enable = 'on';
%         app.errormedio.Enable = 'on';
        app.calcular.Enable = 'on';
        app.Switch.Enable = 'on';
        app.armonicos.Enable = 'on';
        app.estado.Value = 'Modifique sus parámetros y oprima CALCULAR';
        
        app.inicio.Enable = 'off';
        
        end

        % Button pushed function: calcular
        function calcularButtonPushed(app, event)
            app.estado.Value = 'Calculando... Por favor, espere.';
            app.grafico.cla;
            signalValue = app.signal.Value;
            switchValue = app.Switch.Value;
            n = app.armonicos.Value;
            A = 1;
            f = app.frec.Value;
            Fs = 44100;
            T = 1/f;
            t = -(6/2)*T:1/Fs:(6/2)*T-(1/Fs);


            %error criteria 
            if switchValue == '1'
                app.estado.Value = 'Calculando';
                [y,polFourier] = signalGen(t,A,f,signalValue,n);
            else
                app.estado.Value = 'Calculando';
                valor = app.errormedio.Value;
                [n, ~, polFourier, y] = mse_criterion(t, f, A, valor, signalValue);
                app.armonicos.Value = n;
            end
            
            
            %%%%%%
            
            %%%%%%
            
            plot(app.grafico,t,y);
            hold(app.grafico,'on');
            plot(app.grafico,t,polFourier);
            legend(app.grafico,{'Wave form' 'Fourier'});
            
            [ecmo,gibbo] = resultados(real(polFourier),y);
            value = app.signal.Value;
            if value == '3'
                app.gibbs.Value = 0;
            else
                app.gibbs.Value = gibbo;
            end
            app.ecmresult.Value = ecmo;
            
            app.estado.Value = 'Completado';

        end

        % Value changed function: signal
        function signalValueChanged(app, event)
            value = app.signal.Value;
            
            switch value
                case '1'
                    app.grafico.Title.String = 'Tren de pulsos';
                    app.grafico.cla;
                    app.gibbs.Enable = 'on';
                case '2'
                    app.grafico.Title.String = 'Diente de sierra';
                    app.grafico.cla;
                    app.gibbs.Enable = 'on';
                case '3'
                    app.grafico.Title.String = 'Triangular';
                    app.grafico.cla;
                    app.gibbs.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GrficadesealesPanel = uipanel(app.UIFigure);
            app.GrficadesealesPanel.Title = 'Gráfica de señales';
            app.grafico = uiaxes(app.GrficadesealesPanel);
            title(app.grafico, 'Tren de pulsos')
            xlabel(app.grafico, 'Tiempo')
            ylabel(app.grafico, 'Amplitud')
            app.ESTADOLabel = uilabel(app.UIFigure);
            app.ESTADOLabel.Text = 'ESTADO';
            app.estado = uieditfield(app.UIFigure, 'text');
            app.estado.ValueChangedFcn = createCallbackFcn(app, @estadoValueChanged, true);
            app.estado.Value = '←                                          ~ ~ ~     I N I C I E     L A     A P L I C A C I Ó N     ~ ~ ~                                            ';
            app.Panel_2 = uipanel(app.UIFigure);
            app.SealDropDownLabel = uilabel(app.Panel_2);
            app.SealDropDownLabel.Text = 'Señal';
            app.signal = uidropdown(app.Panel_2);
            app.signal.Items = {'Pulsos', 'Sierra', 'Triangular'};
            app.signal.ItemsData = {'1', '2', '3', ''};
            app.signal.Editable = 'on';
            app.signal.ValueChangedFcn = createCallbackFcn(app, @signalValueChanged, true);
            app.signal.Enable = 'off';
            app.signal.Value = '1';
            app.FrecuenciaEditFieldLabel = uilabel(app.Panel_2);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.frec = uieditfield(app.Panel_2, 'numeric');
            app.frec.Limits = [1 1000];
            app.frec.ValueDisplayFormat = '%.0f';
            app.frec.Enable = 'off';
            app.frec.Value = 10;
            app.ArmnicosEditFieldLabel = uilabel(app.Panel_2);
            app.ArmnicosEditFieldLabel.Text = 'Armónicos';
            app.armonicos = uieditfield(app.Panel_2, 'numeric');
            app.armonicos.Limits = [1 Inf];
            app.armonicos.Enable = 'off';
            app.armonicos.Value = 10;
            app.ECMEditField_2Label = uilabel(app.Panel_2);
            app.ECMEditField_2Label.Text = 'E.C.M.';
            app.errormedio = uieditfield(app.Panel_2, 'numeric');
            app.errormedio.Limits = [0 Inf];
            app.errormedio.ValueDisplayFormat = '%.5f';
            app.errormedio.Enable = 'off';
            app.errormedio.Value = 0.005;
            app.CriteriodecortePanel = uipanel(app.Panel_2);
            app.CriteriodecortePanel.Title = 'Criterio de corte';
            app.Switch = uiswitch(app.CriteriodecortePanel, 'slider');
            app.Switch.Items = {'ARMONICOS', 'E.C.M.'};
            app.Switch.ItemsData = {'1', '2'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = '1';
            app.Panel_3 = uipanel(app.UIFigure);
            app.calcular = uibutton(app.Panel_3, 'push');
            app.calcular.ButtonPushedFcn = createCallbackFcn(app, @calcularButtonPushed, true);
            app.calcular.Enable = 'off';
            app.calcular.Text = 'CALCULAR';
            app.ResultadosPanel = uipanel(app.Panel_3);
            app.ResultadosPanel.Title = 'Resultados';
            app.GibbsEditFieldLabel = uilabel(app.ResultadosPanel);
            app.GibbsEditFieldLabel.Text = 'Gibbs [%]';
            app.gibbs = uieditfield(app.ResultadosPanel, 'numeric');
            app.gibbs.Editable = 'off';
            app.ECMEditFieldLabel = uilabel(app.ResultadosPanel);
            app.ECMEditFieldLabel.Text = 'E.C.M.';
            app.ecmresult = uieditfield(app.ResultadosPanel, 'numeric');
            app.ecmresult.Editable = 'off';
            app.inicio = uibutton(app.UIFigure, 'push');
            app.inicio.ButtonPushedFcn = createCallbackFcn(app, @inicioButtonPushed, true);
            app.inicio.Text = 'INICIAR APP';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'esssta.gif';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'UNTREF_Logo_2016.svg.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end